classdef Corpuz_Llovit_MatLab_LBYDSP_Audio_EqualizerV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CorpuzLlovitAudioEqualizerUIFigure  matlab.ui.Figure
        BrowseButton         matlab.ui.control.Button
        EqualizerPanel       matlab.ui.container.Panel
        Slider60Hz           matlab.ui.control.Slider
        HzSlider_4Label      matlab.ui.control.Label
        Slider16KHz          matlab.ui.control.Slider
        KHzSlider_5Label     matlab.ui.control.Label
        Slider14KHz          matlab.ui.control.Slider
        KHzSlider_4Label     matlab.ui.control.Label
        Slider12KHz          matlab.ui.control.Slider
        KHzSlider_3Label     matlab.ui.control.Label
        Slider6KHz           matlab.ui.control.Slider
        KhzSliderLabel       matlab.ui.control.Label
        Slider3KHz           matlab.ui.control.Slider
        KHzSlider_2Label     matlab.ui.control.Label
        Slider1KHz           matlab.ui.control.Slider
        KHzSliderLabel       matlab.ui.control.Label
        Slider600Hz          matlab.ui.control.Slider
        HzSlider_3Label      matlab.ui.control.Label
        Slider310Hz          matlab.ui.control.Slider
        HzSlider_2Label      matlab.ui.control.Label
        HzSlider             matlab.ui.control.Slider
        HzSliderLabel        matlab.ui.control.Label
        PresetDropDown       matlab.ui.control.DropDown
        PresetDropDownLabel  matlab.ui.control.Label
        SliderPreamp         matlab.ui.control.Slider
        PreampSliderLabel    matlab.ui.control.Label
        WaveformPanel        matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        PlayButton           matlab.ui.control.Button
        BrowseSong           matlab.ui.control.TextArea
        Image                matlab.ui.control.Image
    end

    
   properties (Access = private)
    audioData       % Audio data
    samplingRate    % Sampling frequency
    player          % audioplayer object
    gainPreamp = 0; % gain value for Preamp
    fileinfo        % info about the audio file
    filename        % file path
    starting        % start portin of audiofile
    ending          % end portion of audiofile
    end
           
    
    methods (Access = private)
        
        function preAmp(app)
                [app.audioData, app.samplingRate] = audioread(app.filename);
                app.audioData = app.gainPreamp * app.audioData;
                plot(app.UIAxes, (1:length(app.audioData))/app.samplingRate, app.audioData, ":");
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude');
        end
    end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, BrowseSong
        function BrowseButtonPushed(app, event)
            pause(app.player);
             app.PlayButton.Text = 'Play';
            
            [file, path] = uigetfile({'*.mp3;*.wav', 'Audio Files (*.mp3, *.wav)'});
            if isequal(file, 0)
                return; % User canceled the file selection
            end

            app.filename = fullfile(path,file);
            app.fileinfo = audioinfo(app.filename);
            [app.audioData, app.samplingRate] = audioread(app.filename);
            plot(app.UIAxes, (1:length(app.audioData))/app.samplingRate, app.audioData, ":");
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Audio Waveform');
            app.player = audioplayer(app.audioData, app.samplingRate);
            app.BrowseSong.Value = sprintf('%s', file);

           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % if strcmp(app.audioPlayer.Running, 'off')
            %     play(app.audioPlayer);
            %     startSpectrumAnalysis(app);
            %     app.PauseButton.Visible = 'on';
            %     app.PlayButton.Visible = 'off';
            % end

            if isempty(app.player)
                return;
            end

            if isplaying(app.player)
                  stop(app.player);
                  app.PlayButton.Text = 'Play';
              else
                  resume(app.player);
                  app.PlayButton.Text = 'Stop';
            end

            while isplaying(app.player)
                if ~isplaying(app.player)
                    app.PlayButton.Text = 'Play';
                end
            end
        end

        % Value changed function: SliderPreamp
        function SliderPreampValueChanged(app, event)
            % value = app.SliderPreamp.Value;
            % app.gainPreamp = value;
            % 
            %  if isempty(app.player)
            %     return;
            % end
            % 
            %  if strcmp(app.player.running, 'on')
            %     pause(app.player);
            %     preAmp(app);
            %     resume(app.player);
            %   else
            %     preAmp(app);
            %  end

        end

        % Value changing function: SliderPreamp
        function SliderPreampValueChanging(app, event)
            changingValue = event.Value;
            app.gainPreamp = changingValue;
            
             if isempty(app.player)
                return;
            end

             if strcmp(app.player.running, 'on')
                pause(app.player);
                preAmp(app);
                resume(app.player);
              else
                preAmp(app);
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CorpuzLlovitAudioEqualizerUIFigure = uifigure('Visible', 'off');
            app.CorpuzLlovitAudioEqualizerUIFigure.Name = 'Corpuz-Llovit Audio Equalizer';
            app.CorpuzLlovitAudioEqualizerUIFigure.Icon = fullfile(pathToMLAPP, 'audio-equalizer.512x506.png');
            app.Image = uiimage(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fruitger_Aero_BG.png');
            app.BrowseSong = uitextarea(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.BrowseSong.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseSong.Editable = 'off';
            app.BrowseSong.Tooltip = {'Displays the current song playing'};
            app.BrowseSong.Value = {'Browse a song to play'};
            app.PlayButton = uibutton(app.CorpuzLlovitAudioEqualizerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.WaveformPanel = uipanel(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.WaveformPanel.Tooltip = {'Visual Aid to see the Waveforms of the Audio File'};
            app.WaveformPanel.Title = 'Waveform';
            app.WaveformPanel.Tag = 'Panel Waveform';
            app.UIAxes = uiaxes(app.WaveformPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.YTick = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.UIAxes.YTickLabel = {'-12 dB'; '-11 dB'; '-10 dB'; '-9 dB'; '-8 dB'; '-7 dB'; '-6 dB'; '-5 dB'; '-4 dB'; '-3 dB'; '-2 dB'; '-1 dB'; '0 dB'; '1 dB'; '2 dB'; '3 dB'; '4 dB'; '5 dB'; '6 dB'; '7 dB'; '8 dB'; '9 dB'; '10 dB'; '11 dB'; '12 dB'};
            app.EqualizerPanel = uipanel(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.EqualizerPanel.Title = 'Equalizer';
            app.PreampSliderLabel = uilabel(app.EqualizerPanel);
            app.PreampSliderLabel.Text = 'Preamp';
            app.SliderPreamp = uislider(app.EqualizerPanel);
            app.SliderPreamp.Limits = [-12 12];
            app.SliderPreamp.MajorTicks = [-12 0 12];
            app.SliderPreamp.MajorTickLabels = {'-12 dB', '0 dB', '12 dB'};
            app.SliderPreamp.ValueChangedFcn = createCallbackFcn(app, @SliderPreampValueChanged, true);
            app.SliderPreamp.ValueChangingFcn = createCallbackFcn(app, @SliderPreampValueChanging, true);
            app.SliderPreamp.Value = 0.1;
            app.PresetDropDownLabel = uilabel(app.EqualizerPanel);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.EqualizerPanel);
            app.HzSliderLabel = uilabel(app.EqualizerPanel);
            app.HzSliderLabel.Text = '170 Hz';
            app.HzSlider = uislider(app.EqualizerPanel);
            app.HzSlider.Limits = [-12 12];
            app.HzSlider.MajorTicks = [-12 0 12];
            app.HzSlider.MajorTickLabels = {'-12 dB', '0 dB', '12 dB'};
            app.HzSlider_2Label = uilabel(app.EqualizerPanel);
            app.HzSlider_2Label.Text = '310 Hz';
            app.Slider310Hz = uislider(app.EqualizerPanel);
            app.Slider310Hz.Limits = [-12 12];
            app.Slider310Hz.MajorTicks = [-12 0 12];
            app.Slider310Hz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.HzSlider_3Label = uilabel(app.EqualizerPanel);
            app.HzSlider_3Label.Text = '600 Hz';
            app.Slider600Hz = uislider(app.EqualizerPanel);
            app.Slider600Hz.Limits = [-12 12];
            app.Slider600Hz.MajorTicks = [-12 0 12];
            app.Slider600Hz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '1 KHz';
            app.Slider1KHz = uislider(app.EqualizerPanel);
            app.Slider1KHz.Limits = [-12 12];
            app.Slider1KHz.MajorTicks = [-12 0 12];
            app.Slider1KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_2Label.Text = '3 KHz';
            app.Slider3KHz = uislider(app.EqualizerPanel);
            app.Slider3KHz.Limits = [-12 12];
            app.Slider3KHz.MajorTicks = [-12 0 12];
            app.Slider3KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KhzSliderLabel = uilabel(app.EqualizerPanel);
            app.KhzSliderLabel.Text = '6 Khz';
            app.Slider6KHz = uislider(app.EqualizerPanel);
            app.Slider6KHz.Limits = [-12 12];
            app.Slider6KHz.MajorTicks = [-12 0 12];
            app.Slider6KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_3Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_3Label.Text = '12 KHz';
            app.Slider12KHz = uislider(app.EqualizerPanel);
            app.Slider12KHz.Limits = [-12 12];
            app.Slider12KHz.MajorTicks = [-12 0 12];
            app.Slider12KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_4Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_4Label.Text = '14 KHz';
            app.Slider14KHz = uislider(app.EqualizerPanel);
            app.Slider14KHz.Limits = [-12 12];
            app.Slider14KHz.MajorTicks = [-12 0 12];
            app.Slider14KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_5Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_5Label.Text = '16 KHz';
            app.Slider16KHz = uislider(app.EqualizerPanel);
            app.Slider16KHz.Limits = [-12 12];
            app.Slider16KHz.MajorTicks = [-12 0 12];
            app.Slider16KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.HzSlider_4Label = uilabel(app.EqualizerPanel);
            app.HzSlider_4Label.Text = '60 Hz';
            app.Slider60Hz = uislider(app.EqualizerPanel);
            app.Slider60Hz.Limits = [-12 12];
            app.Slider60Hz.MajorTicks = [-12 0 12];
            app.Slider60Hz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.BrowseButton = uibutton(app.CorpuzLlovitAudioEqualizerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = fullfile(pathToMLAPP, 'Aero Circle 08.png');
            app.BrowseButton.Tooltip = {'Browse songs in your current file'};
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.CorpuzLlovitAudioEqualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Corpuz_Llovit_MatLab_LBYDSP_Audio_EqualizerV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CorpuzLlovitAudioEqualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CorpuzLlovitAudioEqualizerUIFigure)
        end
    end
end