classdef Corpuz_Llovit_MatLab_LBYDSP_Audio_Equalizer_BennV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CorpuzLlovitAudioEqualizerUIFigure  matlab.ui.Figure
        BrowseButton         matlab.ui.control.Button
        EqualizerPanel       matlab.ui.container.Panel
        Slider60Hz           matlab.ui.control.Slider
        PreampSlider_2Label  matlab.ui.control.Label
        Slider16KHz          matlab.ui.control.Slider
        KHzSlider_5Label     matlab.ui.control.Label
        Slider14KHz          matlab.ui.control.Slider
        KHzSlider_4Label     matlab.ui.control.Label
        Slider12KHz          matlab.ui.control.Slider
        KHzSlider_3Label     matlab.ui.control.Label
        Slider6KHz           matlab.ui.control.Slider
        KhzSliderLabel       matlab.ui.control.Label
        Slider3KHz           matlab.ui.control.Slider
        KHzSlider_2Label     matlab.ui.control.Label
        Slider1KHz           matlab.ui.control.Slider
        KHzSliderLabel       matlab.ui.control.Label
        Slider600Hz          matlab.ui.control.Slider
        HzSlider_3Label      matlab.ui.control.Label
        Slider310Hz          matlab.ui.control.Slider
        HzSlider_2Label      matlab.ui.control.Label
        Slider170Hz          matlab.ui.control.Slider
        HzSliderLabel        matlab.ui.control.Label
        PresetDropDown       matlab.ui.control.DropDown
        PresetDropDownLabel  matlab.ui.control.Label
        PreampSlider         matlab.ui.control.Slider
        PreampSliderLabel    matlab.ui.control.Label
        WaveformPanel        matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        PlayButton           matlab.ui.control.Button
        BrowseSong           matlab.ui.control.TextArea
        Image                matlab.ui.control.Image
    end

    
   properties (Access = private)
    audioData       % Audio data
    samplingRate    % Sampling frequency
    player          % audioplayer object
    equalizedAudio
    preAmp
    filterState60Hz
    filterState170Hz
    filterState310Hz
    filterState600Hz
    filterState1kHz
    filterState3kHz
    filterState6kHz
    filterState12kHz
    filterState14kHz
    filterState16kHz
    end
           
    methods (Access = private)
        function smoothPlaybackTransition(app, newAudio, startSample)
            fadeTime = 0.05; % 50ms crossfade
            fadeSamples = round(fadeTime * app.samplingRate);
            
            % Get current playback audio if available
            try
                currentAudio = getaudiodata(app.player);
            catch
                currentAudio = newAudio; % Fallback if no current audio
            end
            startSample = max(1, startSample - round(fadeSamples/2));
            
            % Create crossfade windows
            fadeOut = linspace(1, 0, fadeSamples)';
            fadeIn = linspace(0, 1, fadeSamples)';
            
            % Apply crossfade to new audio
            transitionAudio = newAudio;
            transitionRange = startSample:min(startSample+fadeSamples-1, size(newAudio,1));
            
            if length(transitionRange) == fadeSamples
                transitionAudio(transitionRange,:) = ...
                    currentAudio(transitionRange,:) .* fadeOut + ...
                    newAudio(transitionRange,:) .* fadeIn;
            end
            
            % Update the equalized audio
            app.equalizedAudio = transitionAudio;
            
            % Restart playback
            if isplaying(app.player)
                stop(app.player);
            end
            app.player = audioplayer(app.equalizedAudio, app.samplingRate);
            play(app.player, startSample);
        end

        function equalizeAudio(app, gain, fc_low, fc_high, fs, filterState, filterStateName)
                filterOrder = 100;
                b = fir1(filterOrder, [fc_low, fc_high]/(fs/2), 'bandpass');
                a = 1;
                % Check if we need to initialize the filter state
                if ~isfield(app, filterStateName) || isempty(filterState)
                    app.filterState170Hz = zeros(max(length(a),length(b))-1, size(app.audioData,2));
                end
                
                % Apply filter with state preservation
                [filtered_signal, app.filterState170Hz] = filter(b, a, app.audioData, app.filterState170Hz);
                
                mixFactor = 0.7; % Prevents clipping when combining signals
                app.equalizedAudio = app.audioData * (1-mixFactor) + (gain * filtered_signal) * mixFactor;
                
                % Normalize to prevent clipping
                maxVal = max(abs(app.equalizedAudio(:)));
                if maxVal > 1
                    app.equalizedAudio = app.equalizedAudio / maxVal;
                end
                updateWaveform(app);
        end

        function updateWaveform(app)
             if isempty(app.audioData)
                return;
             end
             plot(app.UIAxes, (1:length(app.equalizedAudio))/app.samplingRate, app.equalizedAudio, ':');
             xlabel(app.UIAxes, 'Time (s)');
             ylabel(app.UIAxes, 'Amplitude');
             title(app.UIAxes, 'Audio Waveform');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, BrowseSong
        function BrowseButtonPushed(app, event)
            pause(app.player);
             app.PlayButton.Text = 'Play';
            
            [file, path] = uigetfile({'*.mp3;*.wav', 'Audio Files (*.mp3, *.wav)'});
            if isequal(file, 0)
                return; % User canceled the file selection
            end
            [app.audioData, app.samplingRate] = audioread(fullfile(path, file));
            app.equalizedAudio = app.audioData;
            plot(app.UIAxes, (1:length(app.equalizedAudio))/app.samplingRate, app.audioData, ":");
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Audio Waveform');
            app.player = audioplayer(app.audioData, app.samplingRate);
            app.BrowseSong.Value = sprintf('%s', file);

           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % if strcmp(app.audioPlayer.Running, 'off')
            %     play(app.audioPlayer);
            %     startSpectrumAnalysis(app);
            %     app.PauseButton.Visible = 'on';
            %     app.PlayButton.Visible = 'off';
            % end

            if isempty(app.player)
                return;
            end

            if strcmp(app.player.running, 'on')
                  pause(app.player);
                  app.PlayButton.Text = 'Play';
              else
                  resume(app.player);
                  app.PlayButton.Text = 'Pause';
             end
        end

        % Callback function
        function SettingsButtonPushed(app, event)
            app.CorpuzLlovitAudioEqualizerUIFigure.Position = [300 200 983 660];
            app.Image.Position = [0 0 983 660];

            app.BrowseSong.Position = [99 621 532 33];
            app.BrowseButton.Position = [12 624 74 23];
            app.PlayButton.Position = [290 32 61 23];
            app.WaveformPanel.Position = [200 250 618 255];
            app.UIAxes.Position = [12 30 590 203];
            app.Label.Position = [1 31 25 22];
            app.PositionSlider.Position = [47 40 561 3];
        end

        % Callback function
        function PauseButtonPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
                app.PauseButton.Visible = 'off';
                app.PlayButton.Visible = 'on';
            end
            
            
            % if ~isempty(app.audioPlayer) && strcmp(app.audioPlayer.Running, 'on')
            %     pause(app.audioPlayer);
            %     stopSpectrumAnalysis(app);
            %     app.PlayButton.Visible = 'on';
            %     app.PauseButton.Visible = 'off';
            % end

        end

        % Value changing function: Slider170Hz
        function Slider170HzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 150; fc_high = 190;
            filterstate = app.filterState170Hz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState170Hz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider310Hz
        function Slider310HzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 290; fc_high = 330;
            filterstate = app.filterState310Hz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState310Hz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider600Hz
        function Slider600HzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 580; fc_high = 620;
            filterstate = app.filterState600Hz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState600Hz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider1KHz
        function Slider1KHzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 980; fc_high = 1020;
            filterstate = app.filterState1kHz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState1kHz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider3KHz
        function Slider3KHzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 2980; fc_high = 3020;
            filterstate = app.filterState3kHz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState3kHz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider6KHz
        function Slider6KHzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 5980; fc_high = 6020;
            filterstate = app.filterState6kHz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState6kHz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider12KHz
        function Slider12KHzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 11980; fc_high = 12020;
            filterstate = app.filterState12kHz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState12kHz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider14KHz
        function Slider14KHzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 13980; fc_high = 14020;
            filterstate = app.filterState14kHz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState14kHz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider16KHz
        function Slider16KHzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 15980; fc_high = 16020;
            filterstate = app.filterState16kHz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState16kHz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: Slider60Hz
        function Slider60HzValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 40; fc_high = 80;
            filterstate = app.filterState60Hz;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'filterState60Hz');

            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end

        % Value changing function: PreampSlider
        function PreampSliderValueChanging(app, event)
            gain_dB = event.Value;
            gain = db2mag(gain_dB);
                
            fs = app.samplingRate;
            fc_low = 20; fc_high = 20000;
            filterstate = app.preAmp;
            app.equalizeAudio(gain, fc_low, fc_high, fs, filterstate, 'Preamp');
    
            if isplaying(app.player)
              currentSample = get(app.player, 'CurrentSample');
            else
                currentSample = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CorpuzLlovitAudioEqualizerUIFigure = uifigure('Visible', 'off');
            app.CorpuzLlovitAudioEqualizerUIFigure.Name = 'Corpuz-Llovit Audio Equalizer';
            app.CorpuzLlovitAudioEqualizerUIFigure.Icon = fullfile(pathToMLAPP, 'audio-equalizer.512x506.png');
            app.Image = uiimage(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fruitger_Aero_BG.png');
            app.BrowseSong = uitextarea(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.BrowseSong.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseSong.Editable = 'off';
            app.BrowseSong.Tooltip = {'Displays the current song playing'};
            app.BrowseSong.Value = {'Browse a song to play'};
            app.PlayButton = uibutton(app.CorpuzLlovitAudioEqualizerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.WaveformPanel = uipanel(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.WaveformPanel.Tooltip = {'Visual Aid to see the Waveforms of the Audio File'};
            app.WaveformPanel.Title = 'Waveform';
            app.WaveformPanel.Tag = 'Panel Waveform';
            app.UIAxes = uiaxes(app.WaveformPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.YTickLabel = {'0%'; '20%'; '40%'; '60%'; '80%'; '1'};
            app.EqualizerPanel = uipanel(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.EqualizerPanel.Title = 'Equalizer';
            app.PreampSliderLabel = uilabel(app.EqualizerPanel);
            app.PreampSliderLabel.Text = 'Preamp';
            app.PreampSlider = uislider(app.EqualizerPanel);
            app.PreampSlider.Limits = [-12 12];
            app.PreampSlider.MajorTicks = [-12 0 12];
            app.PreampSlider.MajorTickLabels = {'-12 dB', '0 dB', '12 dB'};
            app.PreampSlider.ValueChangingFcn = createCallbackFcn(app, @PreampSliderValueChanging, true);
            app.PresetDropDownLabel = uilabel(app.EqualizerPanel);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.EqualizerPanel);
            app.HzSliderLabel = uilabel(app.EqualizerPanel);
            app.HzSliderLabel.Text = '170 Hz';
            app.Slider170Hz = uislider(app.EqualizerPanel);
            app.Slider170Hz.Limits = [-12 12];
            app.Slider170Hz.MajorTicks = [-12 0 12];
            app.Slider170Hz.ValueChangingFcn = createCallbackFcn(app, @Slider170HzValueChanging, true);
            app.HzSlider_2Label = uilabel(app.EqualizerPanel);
            app.HzSlider_2Label.Text = '310 Hz';
            app.Slider310Hz = uislider(app.EqualizerPanel);
            app.Slider310Hz.Limits = [-12 12];
            app.Slider310Hz.MajorTicks = [-12 0 12];
            app.Slider310Hz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider310Hz.ValueChangingFcn = createCallbackFcn(app, @Slider310HzValueChanging, true);
            app.HzSlider_3Label = uilabel(app.EqualizerPanel);
            app.HzSlider_3Label.Text = '600 Hz';
            app.Slider600Hz = uislider(app.EqualizerPanel);
            app.Slider600Hz.Limits = [-12 12];
            app.Slider600Hz.MajorTicks = [-12 0 12];
            app.Slider600Hz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider600Hz.ValueChangingFcn = createCallbackFcn(app, @Slider600HzValueChanging, true);
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '1 KHz';
            app.Slider1KHz = uislider(app.EqualizerPanel);
            app.Slider1KHz.Limits = [-12 12];
            app.Slider1KHz.MajorTicks = [-12 0 12];
            app.Slider1KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider1KHz.ValueChangingFcn = createCallbackFcn(app, @Slider1KHzValueChanging, true);
            app.KHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_2Label.Text = '3 KHz';
            app.Slider3KHz = uislider(app.EqualizerPanel);
            app.Slider3KHz.Limits = [-12 12];
            app.Slider3KHz.MajorTicks = [-12 0 12];
            app.Slider3KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider3KHz.ValueChangingFcn = createCallbackFcn(app, @Slider3KHzValueChanging, true);
            app.KhzSliderLabel = uilabel(app.EqualizerPanel);
            app.KhzSliderLabel.Text = '6 Khz';
            app.Slider6KHz = uislider(app.EqualizerPanel);
            app.Slider6KHz.Limits = [-12 12];
            app.Slider6KHz.MajorTicks = [-12 0 12];
            app.Slider6KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider6KHz.ValueChangingFcn = createCallbackFcn(app, @Slider6KHzValueChanging, true);
            app.KHzSlider_3Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_3Label.Text = '12 KHz';
            app.Slider12KHz = uislider(app.EqualizerPanel);
            app.Slider12KHz.Limits = [-12 12];
            app.Slider12KHz.MajorTicks = [-12 0 12];
            app.Slider12KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider12KHz.ValueChangingFcn = createCallbackFcn(app, @Slider12KHzValueChanging, true);
            app.KHzSlider_4Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_4Label.Text = '14 KHz';
            app.Slider14KHz = uislider(app.EqualizerPanel);
            app.Slider14KHz.Limits = [-12 12];
            app.Slider14KHz.MajorTicks = [-12 0 12];
            app.Slider14KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider14KHz.ValueChangingFcn = createCallbackFcn(app, @Slider14KHzValueChanging, true);
            app.KHzSlider_5Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_5Label.Text = '16 KHz';
            app.Slider16KHz = uislider(app.EqualizerPanel);
            app.Slider16KHz.Limits = [-12 12];
            app.Slider16KHz.MajorTicks = [-12 0 12];
            app.Slider16KHz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider16KHz.ValueChangingFcn = createCallbackFcn(app, @Slider16KHzValueChanging, true);
            app.PreampSlider_2Label = uilabel(app.EqualizerPanel);
            app.PreampSlider_2Label.Text = 'Preamp';
            app.Slider60Hz = uislider(app.EqualizerPanel);
            app.Slider60Hz.Limits = [-12 12];
            app.Slider60Hz.MajorTicks = [-12 0 12];
            app.Slider60Hz.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.Slider60Hz.ValueChangingFcn = createCallbackFcn(app, @Slider60HzValueChanging, true);
            app.BrowseButton = uibutton(app.CorpuzLlovitAudioEqualizerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = fullfile(pathToMLAPP, 'Aero Circle 08.png');
            app.BrowseButton.Tooltip = {'Browse songs in your current file'};
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.CorpuzLlovitAudioEqualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Corpuz_Llovit_MatLab_LBYDSP_Audio_Equalizer_BennV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CorpuzLlovitAudioEqualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CorpuzLlovitAudioEqualizerUIFigure)
        end
    end
end